classdef Cal_Analyse_Coordination_ver1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        affichagerasterButton         matlab.ui.control.Button
        SelectiondeRepertoireResultatsButton  matlab.ui.control.Button
        ListeFichierResultatsListBox  matlab.ui.control.ListBox
        ListeFichierResultatsListBoxLabel  matlab.ui.control.Label
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Chemin %
        Fichiers
        select_liste_fichiers
        Resultat
        Resultat_Period
        DFoF
        DFoFSelect
        Parametre
        Period
        Phase

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectiondeRepertoireResultatsButton
        function SelectiondeRepertoireResultatsButtonPushed(app, event)
            [app.Fichiers,app.Chemin]=uigetfile("*.csv","MultiSelect",'on');
            if ~isequal([app.Chemin,app.Fichiers],[0,0])
                app.ListeFichierResultatsListBox.Items=app.Fichiers;
            end
        end

        % Value changed function: ListeFichierResultatsListBox
        function ListeFichierResultatsListBoxValueChanged(app, event)
            app.select_liste_fichiers = app.ListeFichierResultatsListBox.Value;

        end

        % Button pushed function: affichagerasterButton
        function affichagerasterButtonPushed(app, event)

            liste=app.select_liste_fichiers;
            if ~isempty(liste)
                app.Resultat=[];app.Parametre=[];app.Period=[]; app.Resultat_Period=[];

                liste= string(liste);
                nbre=size(liste,2);

                index=contains(liste,"Resultat_CalciumDFoF") ;
                Nomfichier=strcat(app.Chemin, liste(index));
                app.DFoF=table2array(readtable(Nomfichier,"VariableNamingRule","preserve"));


                index=contains(liste,"ParamDetection");
                Nomfichier=strcat(app.Chemin, liste(index));
                app.Parametre=readtable(Nomfichier,"VariableNamingRule","preserve");


                index=contains(liste,"ProtocolTime");
                Nomfichier=strcat(app.Chemin, liste(index));
                app.Period=readtable(Nomfichier,"VariableNamingRule","preserve");

                nbre=nbre-3;


                index=find(contains(liste,  "Protocol No " ));

                for k=1:length(index)
                    Nomfichier=strcat(app.Chemin, liste(index(k)));
                    app.Resultat_Period{k}=table2array(readtable(Nomfichier,"VariableNamingRule","preserve"));
                end
                nbre=nbre-length(index);

                Nomfichier=strcat(app.Chemin, liste(nbre));
                
                app.Resultat=readtable(Nomfichier,"VariableNamingRule","preserve");


                %*****************************************************************************
                data_resultat_t=table2array(app.Resultat);
                liste_cell=unique(data_resultat_t(:,1));
                Nbre_cell=length(liste_cell);
                app.DFoFSelect=zeros(size(app.DFoF,1),Nbre_cell+1);
                app.DFoFSelect(:,1)=app.DFoF(:,1);
                k=2;
                for cell=1:Nbre_cell
                    %
                    if ~isempty(find(data_resultat_t(:,1)==liste_cell(cell),1)) 
                     app.DFoFSelect(:,k)=app.DFoF(:,liste_cell(cell)+1);
                     k=k+1;
                    end
                    
                end
                %*****************************************************************************************
                

                %dt=round(mean(diff( app.DFoF(:,1))));
                gradientcouleur=jet(size(app.Period,1));

                plot( app.UIAxes_2, app.DFoFSelect(:,1), app.DFoFSelect(:,2:end))


                ammp_max= max(max( app.DFoFSelect(:,2:end)));

                for i=1:size(app.Period,1)

                    x=[app.Period.Start(i) app.Period.Start(i) app.Period.Stop(i)  app.Period.Stop(i)];
                    y=[0 ammp_max  ammp_max 0];


                    %shape =
                    patch(app.UIAxes_2,x,y,gradientcouleur(i,:),'FaceAlpha',0.3,'EdgeColor','none');

                end
                xlim(app.UIAxes_2,[0 app.DFoF(end,1)]);
                %******************************************************************************************


                lambda_Matrix=zeros(size(app.DFoF(:,2:end),2),size(app.DFoF(:,2:end),2));

                for i=1:size(app.DFoF(:,2:end),2)
                    for j=1:size(app.DFoF(:,2:end),2)
                        phi_1=hilb_phase(app.DFoF(:,i+1));
                        phi_2=hilb_phase(app.DFoF(:,j+1));
                        lambda=strobo(phi_1,phi_2,20);
                        lambda_Matrix(i,j)=lambda;
                    end
                end
                imagesc(app.UIAxes_3, lambda_Matrix');
                axis(app.UIAxes_3,'tight')


                %****************************************************************************
                %app.Phase=cell(size(app.Resultat_Period,2),size(app.DFoF,2)-1);
                lambda_MatrixTotalP={1,size(app.Resultat_Period,2)};
                couplesig=[];
                lambda_ListeTotalP={1,size(app.Resultat_Period,2)};
               
                figure
                for period=1: size(app.Resultat_Period,2)
                   lambda_valeurs=[];
                    data_resultat1=app.Resultat_Period{1,period};%Time_Event=cell(1,size(app.DFoF,2)-1);


                    %
                    %     k=1;
                    hold(app.UIAxes,'on')

                    for i=1:size(app.DFoF,2)-1
                        %
                        index=find(data_resultat1(:,1)==i)    ;
                        if ~ isempty(index)
                            Spike=data_resultat1(index,3);
                            %Time_Event{i}=Spike;
                            s1=ones(1,length(Spike))*i ;
                            plot(app.UIAxes, Spike,s1,'r|')
                        end
                    end
                    hold(app.UIAxes,'off')
                    xlim(app.UIAxes,[0 app.DFoF(end,1)]);
                    %*****************************************************************************
                    % calcul de phase
                    %*****************************************************************************
                    lambda_MatrixP= zeros(size( app.DFoFSelect(:,2:end),2),size( app.DFoFSelect(:,2:end),2));
                    t_index= app.DFoFSelect(:, 1)>=app.Period.Start(period) & app.DFoFSelect(:,1)<=app.Period.Stop( period);
                   % time_period=app.DFoF(t_index,1);
                   k=1;
                    for i=1: size(app.DFoFSelect(t_index,2:end),2)
                        for j=1:size( app.DFoFSelect(t_index,2:end),2)
                            if i~=j
                            phi_1=hilb_phase( app.DFoFSelect(t_index,i+1));
                            phi_2=hilb_phase( app.DFoFSelect(t_index,j+1));
                            lambda=strobo(phi_1,phi_2,20);
                            lambda_MatrixP(i,j)=lambda;
                            if period==1
                            couplesig{k}=[num2str(liste_cell(i)) '-' num2str(j)];
                            end
                            lambda_valeurs(k)=lambda;
                             k=k+1;
                            end

                        end

                    end
                    lambda_MatrixTotalP{period}=lambda_MatrixP;
                     lambda_ListeTotalP{period}= lambda_valeurs;


                    subplot(2,size(app.Resultat_Period,2),period)

                    imagesc( lambda_MatrixP');
                      axis('tight')
                     subplot(2,size(app.Resultat_Period,2),period+size(app.Resultat_Period,2))
                      histogram( lambda_valeurs,100)


                    % for i=1:size(app.DFoF,2)-1
                    % Spike=Time_Event{i};
                    % app.Phase{period,i}=GetPhaseSpikes(Spike,length(time_period), time_period);
                    %
                    %
                    % end
                    %
                    %  P=cellmat(app.Phase(period,:));
                    %   P1=reshape(P,[],size(app.DFoF(:,2:end),2));
                    %  for i=1:size(app.DFoF(:,2:end),2)
                    %      for j=1:size(app.DFoF(:,2:end),2)
                    %
                    %
                    %
                    %
                    %
                    %      end
                    %  end


                    %******************************************************************************
                    %
                    %******************************************************************************
                end
                 %*********************************************************************************
                 % Sauvegarde  des resultats
                 %*********************************************************************************
                 temp=cell2mat(lambda_ListeTotalP');
                 temp=temp';
                  couplesig=couplesig';

                 NomCoord=[app.Chemin,app.Fichiers{1}, '_COORDINATION.csv' ];

                 outputFid = fopen(NomCoord, 'w');

                   fprintf(outputFid,'%s\t','Couple Cellules');
                     for i=1:size(temp,2)
                         fprintf(outputFid,'%s\t',['Periodes_',num2str(i),'_Val_Lambda']);
                     end
                  fprintf(outputFid,'\n');
                 for i=1:size(temp,1) 
                     fprintf(outputFid,'%s\t',char(couplesig{i}));  
                    for j=1:size(temp,2)
                      
                     fprintf(outputFid,'%s\t',num2str(temp(i,j)));
                    end
                      fprintf(outputFid,'\n');
                 end

                 fclose(outputFid);


            else
                msgbox("Les fichiers ne sont pas sélectionnés");
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raster')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Cellules')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Cellules')
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'DFoF')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'lambda Signal total')
            xlabel(app.UIAxes_3, 'cellules')
            ylabel(app.UIAxes_3, 'cellules')
            zlabel(app.UIAxes_3, 'Z')
            app.ListeFichierResultatsListBoxLabel = uilabel(app.UIFigure);
            app.ListeFichierResultatsListBoxLabel.Text = 'ListeFichierResultats';
            app.ListeFichierResultatsListBox = uilistbox(app.UIFigure);
            app.ListeFichierResultatsListBox.Items = {};
            app.ListeFichierResultatsListBox.Multiselect = 'on';
            app.ListeFichierResultatsListBox.ValueChangedFcn = createCallbackFcn(app, @ListeFichierResultatsListBoxValueChanged, true);
            app.ListeFichierResultatsListBox.Value = {};
            app.SelectiondeRepertoireResultatsButton = uibutton(app.UIFigure, 'push');
            app.SelectiondeRepertoireResultatsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectiondeRepertoireResultatsButtonPushed, true);
            app.SelectiondeRepertoireResultatsButton.Text = 'Selection de Repertoire Resultats';
            app.affichagerasterButton = uibutton(app.UIFigure, 'push');
            app.affichagerasterButton.ButtonPushedFcn = createCallbackFcn(app, @affichagerasterButtonPushed, true);
            app.affichagerasterButton.Text = 'affichage raster ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cal_Analyse_Coordination_ver1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end