classdef Cal_Analyse_ver1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FrequenceEchantillonageHzEditField  matlab.ui.control.NumericEditField
        FrequenceEchantillonageHzEditFieldLabel  matlab.ui.control.Label
        InclusionButton               matlab.ui.control.Button
        ExclusionButton               matlab.ui.control.Button
        extractionprotocoleButton     matlab.ui.control.Button
        protocoleCheckBox             matlab.ui.control.CheckBox
        exclusionsecEditField         matlab.ui.control.NumericEditField
        exclusionsecEditFieldLabel    matlab.ui.control.Label
        cheminEditField               matlab.ui.control.EditField
        cheminEditFieldLabel          matlab.ui.control.Label
        NomfichieraanalyserEditField  matlab.ui.control.EditField
        NomfichieraanalyserEditFieldLabel  matlab.ui.control.Label
        NomFichierResultatsEditField  matlab.ui.control.EditField
        NomFichierResultatsEditFieldLabel  matlab.ui.control.Label
        SauvegardeButton              matlab.ui.control.Button
        FenetreDetectionsecEditField  matlab.ui.control.NumericEditField
        FenetreDetectionLabel         matlab.ui.control.Label
        FenetreLigneBasesecEditField  matlab.ui.control.NumericEditField
        FenetreLigneBasesecEditFieldLabel  matlab.ui.control.Label
        LissageDFFEditField           matlab.ui.control.NumericEditField
        LissageDFFEditFieldLabel      matlab.ui.control.Label
        SeuilDetectionEditField       matlab.ui.control.NumericEditField
        SeuilDetectionEditFieldLabel  matlab.ui.control.Label
        DetectionButton               matlab.ui.control.Button
        CellulesListBox               matlab.ui.control.ListBox
        CellulesListBoxLabel          matlab.ui.control.Label
        OuvrirfichiercsvButton        matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Seuil_Cal            % Seuil
        Fen_Baseline      % fenetre pour median mobile
        Fen_Lissage        % de filtrage du profil calcique
        Fen_detection
        Fen_Exclusion
        fr
        flag_protocol
        c_info
        P_be
        Time_DebFin_Protocol
        NameProtocol
        curChar
        fname
        pathname
        datname
        time
        Calcium
        Spectre
        Seuil_Power
        Numero_Cellules
        Numero_transitoire
        temps_Amplitude
        Amplitude
        temps_mont
        temps_decroi
        tempsm_10
        Amplitudem_10
        tempsm_50
        Amplitudem_50
        tempsm_90
        Amplitudem_90
        tempsd_10
        Amplituded_10
        tempsd_50
        Amplituded_50
        tempsd_90
        Amplituded_90

        AUC
        Debut_Transitoire
        Fin_transitoire
        Duree_Halfwith
        Result_Calcium
        calciumVarnames
        Calcium_normalise
        Calcium_normalise_lisse
        baseline_cal
        dCadt
        ncellfList
        dir_result
        Nom_fichier_Res
        
    end


    methods (Access = private)


        function RemoveZero(app,Index)
             app.Numero_Cellules= app.Numero_Cellules(Index) ;
                app.Numero_transitoire= app.Numero_transitoire(Index) ;
                app.temps_Amplitude=app.temps_Amplitude(Index) ;
                app.Amplitude=app.Amplitude(Index)  ;
                app.Debut_Transitoire=app.Debut_Transitoire(Index) ;
                app.Fin_transitoire=app.Fin_transitoire(Index) ;
                app.temps_mont=app.temps_mont(Index) ;
                app.temps_decroi=app.temps_decroi(Index) ;
                app.tempsm_10=app.tempsm_10(Index) ;
                app. Amplitudem_10=app. Amplitudem_10(Index) ;
                app. tempsm_50= app. tempsm_50(Index) ;
                app. Amplitudem_50=app. Amplitudem_50(Index) ;
                app.tempsm_90=app.tempsm_90(Index) ;
                app.Amplitudem_90=app.Amplitudem_90(Index) ;
                app.tempsd_10=app.tempsd_10(Index) ;
                app.Amplituded_10=app.Amplituded_10(Index) ;
                app.tempsd_50=app.tempsd_50(Index) ;
                app.Amplituded_50=app.Amplituded_50(Index) ;
                app.tempsd_90=app.tempsd_90(Index) ;
                app.Amplituded_90=app.Amplituded_90(Index) ;
                app.AUC=app.AUC(Index) ;
                app.Duree_Halfwith=app.Duree_Halfwith(Index );
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app. Seuil_Cal=2;            % Seuil
            app.Fen_Baseline=800;      % fenetre pour median mobile
            app.Fen_Lissage =0.00005 ;      % de filtrage du profil calcique
            app.LissageDFFEditField.Value= app.Fen_Lissage;
            app.Fen_detection=app.FenetreDetectionsecEditField.Value ;
            app.ncellfList=[];
            app.Fen_Exclusion=50;
            app.Calcium_normalise=[];
            app.Calcium_normalise_lisse=[];
            app.baseline_cal=[];
            app.dCadt=[];
            app.fr=1;
            app.FrequenceEchantillonageHzEditField.Value=app.fr;

        end

        % Button pushed function: OuvrirfichiercsvButton
        function OuvrirfichiercsvButtonPushed(app, event)

            app.Calcium_normalise=[];
            app.Calcium_normalise_lisse=[];
            app.baseline_cal=[];
            app.dCadt=[];

            app.ncellfList=[];

            cla(app.UIAxes,'reset')
            cla(app.UIAxes2,'reset')
            cla(app.UIAxes3,'reset')
            cla(app.UIAxes4,'reset')

            [app.fname,app.pathname]=uigetfile('*.*',' file');%loads file


            if isequal([app.fname,app.pathname],[0,0])
                return
            else

                app.cheminEditField.Value=app.pathname;
                app.NomfichieraanalyserEditField.Value=app.fname;
                app.Nom_fichier_Res=[strtok(app.fname,'.'),'_Resultat.csv'];
                app.NomFichierResultatsEditField.Value= app.Nom_fichier_Res;
                app.datname=[app.pathname app.fname];
                app.dir_result=[app.pathname,'Resultat\'];
                flag_resultats=exist( app.dir_result,'dir');
                if flag_resultats==0
                    mkdir(app.dir_result);
                end


                dataCal=readtable(app.datname, "VariableNamingRule","preserve");
                app.calciumVarnames=dataCal.Properties.VariableNames;

                MatrixCal=table2array(dataCal);


                app.time=MatrixCal(:,1);
                app.Calcium=MatrixCal(:,2:end);
                app.Spectre=[];

                app.Numero_Cellules=zeros(5000,1);
                app.Numero_transitoire=zeros(5000,1);
                app.temps_Amplitude =zeros(5000,1);
                app.Amplitude = zeros(5000,1);
                app.Debut_Transitoire =zeros(5000,1);
                app.Fin_transitoire =zeros(5000,1);

                app.temps_mont=zeros(5000,1) ;
                app.temps_decroi=zeros(5000,1) ;
                app.tempsm_10=zeros(5000,1) ;
                app. Amplitudem_10=zeros(5000,1) ;
                app. tempsm_50=zeros(5000,1) ;
                app. Amplitudem_50=zeros(5000,1) ;
                app.tempsm_90=zeros(5000,1) ;
                app.Amplitudem_90=zeros(5000,1) ;
                app.tempsd_10=zeros(5000,1) ;
                app.Amplituded_10=zeros(5000,1) ;
                app.tempsd_50=zeros(5000,1) ;
                app.Amplituded_50=zeros(5000,1) ;
                app.tempsd_90=zeros(5000,1) ;
                app.Amplituded_90=zeros(5000,1) ;
                app.AUC=zeros(5000,1);
                app.Duree_Halfwith =zeros(5000,1);
              

                app.Result_Calcium = table(app.Numero_Cellules,app.Numero_transitoire,...
                    app.temps_Amplitude ,app.Amplitude ,app.Debut_Transitoire,app.Fin_transitoire,...
                    app.temps_mont,app.temps_decroi,app.tempsm_10,app. Amplitudem_10,app. tempsm_50,...
                    app. Amplitudem_50,app.tempsm_90,app.Amplitudem_90,app.tempsd_10,...
                    app.Amplituded_10,app.tempsd_50,app.Amplituded_50,app.tempsd_90,...
                    app.Amplituded_90,app.AUC,app.Duree_Halfwith);

                plot(app.UIAxes,app.time,app.Calcium(:,:))

                app.UITable.Data= app.Result_Calcium;

            end
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
             app.protocoleCheckBox.Value=0;
            app.ncellfList=[];
            pow.dt      = 1/app.fr;
            pow.timeVec=app.time;
            pow.filter='No';
            %trendDegree=2;

            % Determine sample frequency, frequency resolution and Nyquist frequency
            % mHz is the unit
            pow.mHz            = app.fr;
            pow.N           = 2.^(ceil(log(length(app.time))/log(2)));
            pow.sampleFreq =pow.mHz*1/pow.dt;
            pow.nyquist    =pow.mHz*1/(2*pow.dt);
            pow.freqVec    =pow.sampleFreq*(0:pow.N/2)/pow.N;
            pow.B          =pow.freqVec';
            pow.noCells=size(app.Calcium,2);
            % The most dominant frequencies are stored in pow.freq.vec
            %par.freq.vec = [];

            %*******************************************************
            Fenetre_Baseline=round(app.Fen_Baseline/pow.dt);

            Fenetre_detection=round(app.Fen_detection/ pow.dt) ;    %conversio secondes Nbre points


            %******************************************************
            %start_calcium=[];end_calcium=[];

            k_detecte=1;
            for ncell=1:size(app.Calcium,2)


                b_line=movmedian(app.Calcium(:,ncell),Fenetre_Baseline);
                app.baseline_cal(:,ncell)=b_line;

                app.Calcium_normalise(:,ncell)=(app.Calcium(:,ncell)-b_line)./b_line;
                app.Calcium_normalise_lisse(:,ncell)= smooth(app.Calcium_normalise(:,ncell),app.Fen_Lissage,'loess');

                dcadt=diff(app.Calcium_normalise_lisse(:,ncell),1,1);
                app.dCadt(:,ncell)=[dcadt(1);dcadt];
                indices_detection=app.Calcium_normalise_lisse(:,ncell)<=0;
                app.Calcium_normalise_lisse(  indices_detection,ncell)=0;
                Seuil=app.Seuil_Cal*std( app.Calcium_normalise_lisse(:,ncell));
                [~,~,pidx,~] = peakdet( app.Calcium_normalise_lisse(:,ncell), Seuil,  'threshold');
                %ISI=diff(pidx).*pow.dt;
                debut_pic=app.Fen_Exclusion;fin_pic=abs(size( app.Calcium(:,ncell),1)-app.Fen_Exclusion);

                pidx=pidx(pidx>debut_pic & pidx<fin_pic);

                if ~isempty(pidx)


                    start_cal=zeros(1,length(pidx));End_cal=zeros(1,length(pidx));

                    indexm10=zeros(1,length(pidx));  indexm50=zeros(1,length(pidx));indexm90=zeros(1,length(pidx));
                    indexd10=zeros(1,length(pidx));  indexd50=zeros(1,length(pidx));  indexd90=zeros(1,length(pidx));
                    for j=1:length(pidx)


                        s_f=(pidx(j)-Fenetre_detection);
                        if s_f<1
                            s_f=1;
                        end
                        e_f= (pidx(j)+Fenetre_detection);
                        if e_f>size(app.dCadt(:,ncell),1)
                            e_f=size(app.dCadt(:,ncell),1);
                        end

                        [~,I_max_dvdt]=max( app.dCadt(s_f:e_f,ncell));
                        [~,I_min_dvdt]=min( app.dCadt(s_f:e_f,ncell));

                        ref_rech_max=pidx(j)-I_max_dvdt;
                        ref_rech_min=pidx(j)+I_min_dvdt;


                        for k=ref_rech_max:-1:1%

                            if app.dCadt(k,ncell)<0.0001
                                start_cal(j)=k;
                                break;

                            end
                        end


                        for k=ref_rech_min:1:size(app.dCadt(:,ncell),1)%

                            if app.dCadt(k,ncell)>0.0001
                                End_cal(j)=k;
                                break;

                            end

                        end

                        % montee
                        %******************************************************************************************************************
                        Seuil10=app.Calcium_normalise_lisse(pidx(j),ncell)*0.1;%-abs((app.Calcium_normalise_lisse(pidx(j),ncell)-app.Calcium_normalise_lisse(start_cal(j),ncell))*0.1);
                        Seuil50=app.Calcium_normalise_lisse(pidx(j),ncell)*0.5;%-abs((app.Calcium_normalise_lisse(pidx(j),ncell)-app.Calcium_normalise_lisse(start_cal(j),ncell))*0.5);
                        Seuil90=app.Calcium_normalise_lisse(pidx(j),ncell)*0.9;%-abs((app.Calcium_normalise_lisse(pidx(j),ncell)-app.Calcium_normalise_lisse(start_cal(j),ncell))*0.9);


                        Seuil90=Seuil90*sign(Seuil90);

                       %
                        nbre=abs(start_cal(j)-pidx(j));
                        indexm=find(app.Calcium_normalise_lisse(start_cal(j):end,ncell)>Seuil10,1,'first')-1;
                        indexm10(j)=pidx(j)-abs((indexm-nbre));
                        %
                       
                        indexm=find(app.Calcium_normalise_lisse(start_cal(j):end,ncell)>Seuil50,1,'first')-1;
                        indexm50(j)=pidx(j)-abs((indexm-nbre));
  
                        %
                        indexm=find(app.Calcium_normalise_lisse(start_cal(j):end,ncell)>Seuil90,1,'first')-1;
                        indexm90(j)=pidx(j)-abs((indexm-nbre));
                        % %
                        % % % descente
                        % % %********************************************************************************************************************
                        indexd=find(app.Calcium_normalise_lisse(pidx(j):end,ncell)<Seuil10,1,'first')-1;
                        indexd90(j)=pidx(j)+indexd;
                        indexd=find(app.Calcium_normalise_lisse(pidx(j):end,ncell)<Seuil50,1,'first')-1;
                        indexd50(j)=pidx(j)+indexd;
                        indexd=find(app.Calcium_normalise_lisse(pidx(j):end,ncell)<Seuil90,1,'first')-1;
                        indexd10(j)=pidx(j)+indexd;


                        %*******************************************************************************************************************
                        app.Numero_Cellules(k_detecte)=ncell;
                        app.Numero_transitoire(k_detecte)=j;
                        app.temps_Amplitude(k_detecte)=app.time(pidx(j));
                        app.Amplitude(k_detecte)=app.Calcium_normalise(pidx(j),ncell);
                        app.Debut_Transitoire(k_detecte)= app.time(start_cal(j));%* pow.dt ;
                        app.Fin_transitoire (k_detecte)=app.time( End_cal(j));%* pow.dt ;

                        app.temps_mont(k_detecte)=abs(app.time(pidx(j))-app.time(start_cal(j)));%* pow.dt ;
                        app.temps_decroi(k_detecte)=abs(app.time(pidx(j))-app.time(End_cal(j)));%* pow.dt ;

                        app.tempsm_10(k_detecte)=app.time(indexm10(j));%* pow.dt ;
                        app. Amplitudem_10(k_detecte)=app.Calcium_normalise_lisse( indexm10(j),ncell);
                        app. tempsm_50(k_detecte)=app.time(indexm50(j));%* pow.dt ;
                        app. Amplitudem_50(k_detecte)=app.Calcium_normalise_lisse( indexm50(j),ncell);
                        app.tempsm_90(k_detecte)=app.time(indexm90(j));%* pow.dt ;
                        app.Amplitudem_90(k_detecte)=app.Calcium_normalise_lisse(indexm90(j),ncell);
                        app.tempsd_10(k_detecte)=app.time(indexd90(j));%* pow.dt ;
                        app.Amplituded_10(k_detecte)=app.Calcium_normalise_lisse(indexd90(j),ncell);
                        app.tempsd_50(k_detecte)=app.time(indexd50(j));%* pow.dt ;
                        app.Amplituded_50(k_detecte)=app.Calcium_normalise_lisse( indexd50(j),ncell);
                        app.tempsd_90(k_detecte)=app.time(indexd10(j));%* pow.dt ;
                        app.Amplituded_90(k_detecte)=app.Calcium_normalise_lisse(indexd10(j),ncell);

                        app.AUC(k_detecte) = trapz(app.Calcium_normalise(start_cal(j):End_cal(j),ncell));
                        app.Duree_Halfwith(k_detecte)=abs(app.time(indexd50(j))-app.time( indexm50(j)));

                        k_detecte=  k_detecte+1;

                    end

                    app.Seuil_Power=0.75;
                    par_Resultat=Analyse_FFT(app.time,detrend(app.Calcium_normalise(:,ncell)),pow,app.Seuil_Power);

                    app.Spectre{1,ncell}=par_Resultat.B(:,2);
                    app.Spectre{2,ncell}=par_Resultat.B(:,1);
                    app.Spectre{3,ncell}=pidx;
                    app.Spectre{4,ncell}=Seuil;
                    app.Spectre{5,ncell}=start_cal;
                    app.Spectre{6,ncell}= End_cal;
                    app.Spectre{7,ncell}= indexm10;
                    app.Spectre{8,ncell}=indexm50;
                    app.Spectre{9,ncell}=indexm90;
                    app.Spectre{10,ncell}=indexd10;
                    app.Spectre{11,ncell}=indexd50;
                    app.Spectre{12,ncell}=indexd90;

                    app.UIAxes.Title.String=['Cell', 'No :' ,num2str(ncell)];
                    plot(app.UIAxes,app.time,app.Calcium(:,ncell))
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.time,b_line)
                    hold(app.UIAxes,'off')

                    app.UIAxes3.Title.String=['Resulat Detection Cell', 'No :' ,num2str(ncell)];
                    plot(app.UIAxes3,app.time,app.Calcium_normalise(:,ncell))
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.time,app.Calcium_normalise_lisse(:,ncell),'r','LineWidth',3)
                    plot(app.UIAxes3,app.time(pidx),app.Calcium_normalise(pidx,ncell),'bo','MarkerSize',5  )

                    plot(app.UIAxes3,app.time(1:end),app.dCadt(:,ncell),'c','LineWidth',3)
                    %
                    plot(app.UIAxes3,app.time(start_cal),app.Calcium_normalise(start_cal,ncell),'r>','MarkerSize',8,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time(End_cal),app.Calcium_normalise(End_cal,ncell),'r<','MarkerSize',8,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time( indexm10),app.Calcium_normalise( indexm10,ncell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time( indexm50),app.Calcium_normalise( indexm50,ncell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time( indexm90),app.Calcium_normalise( indexm90,ncell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time( indexd10),app.Calcium_normalise( indexd10,ncell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time( indexd50),app.Calcium_normalise( indexd50,ncell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
                    plot(app.UIAxes3,app.time( indexd90),app.Calcium_normalise( indexd90,ncell),'rd','MarkerSize',3,'MarkerFaceColor','auto')

                    %
                    plot(app.UIAxes3,[app.time(1) app.time(end)],[Seuil Seuil],'g','LineWidth',3)
                    hold(app.UIAxes3,'off')


                    plot(app.UIAxes2,par_Resultat.B(:,1),par_Resultat.B(:,2))
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,[par_Resultat.B(1,1) par_Resultat.B(end,1)],[ app.Seuil_Power  app.Seuil_Power],'LineWidth',3,'Color','yellow')
                    %  set(app.UIAxes2,'Title',text('String','Power Spectrum','Color','r'))
                    % xlabel(app.UIAxes2,'Frequency (Hz)')
                    % ylabel(app.UIAxes2,'Power Spectral Density')
                    xlim(app.UIAxes2,[0 0.2])

                    hold(app.UIAxes2,'off')
                    app.ncellfList{ncell}=num2str(ncell);
                    pause(0.5)
                    %FastPrint(['CelluleNumero_', num2str(ncell)] ,app.dir_result);
                end
            end
            %****************************************************************************
              Index_MAJ=app.Numero_Cellules>0;
               RemoveZero(app,Index_MAJ);
              %******************************************************************************************
            
            app.Result_Calcium = table(app.Numero_Cellules,app.Numero_transitoire,...
                app.temps_Amplitude ,app.Amplitude ,app.Debut_Transitoire,app.Fin_transitoire,...
                app.temps_mont,app.temps_decroi, app.tempsm_10, app. Amplitudem_10,...
                app. tempsm_50,app. Amplitudem_50,app.tempsm_90,app.Amplitudem_90,...
                app.tempsd_10,app.Amplituded_10,app.tempsd_50,app.Amplituded_50,...
                app.tempsd_90,app.Amplituded_90,app.AUC,app.Duree_Halfwith);

            %app.Result_Calcium(k_detecte:end,:)=[];
            app.UITable.Data= app.Result_Calcium;
            app.CellulesListBox.Items=app.ncellfList';
          
            imagesc(app.UIAxes4,app.Calcium_normalise')


        end

        % Value changed function: FenetreDetectionsecEditField
        function FenetreDetectionsecEditFieldValueChanged(app, event)
            app.Fen_detection = app.FenetreDetectionsecEditField.Value;

        end

        % Value changed function: SeuilDetectionEditField
        function SeuilDetectionEditFieldValueChanged(app, event)
            app.Seuil_Cal  = app.SeuilDetectionEditField.Value;

        end

        % Value changed function: LissageDFFEditField
        function LissageDFFEditFieldValueChanged(app, event)
            app.Fen_Lissage = app.LissageDFFEditField.Value;

        end

        % Value changed function: FenetreLigneBasesecEditField
        function FenetreLigneBasesecEditFieldValueChanged(app, event)
            app.Fen_Baseline = app.FenetreLigneBasesecEditField.Value;

        end

        % Clicked callback: CellulesListBox
        function CellulesListBoxClicked(app, event)
            %nucell= event.InteractionInformation.Item;
         
           if contains(app.CellulesListBox.Items(event.InteractionInformation.Item),'X')

               [nbre] = strtok(app.CellulesListBox.Items(event.InteractionInformation.Item),'X');
               nucell=str2double(nbre);
           else
               nucell=str2double(app.CellulesListBox.Items(event.InteractionInformation.Item));
            end
            

            pidx=app.Spectre{3,nucell };
            Power=app.Spectre{1,nucell };
            freq=app.Spectre{2,nucell };
            Seuil=app.Spectre{4,nucell};
            start_cal= app.Spectre{5,nucell};
            End_cal=app.Spectre{6,nucell};
            indexm10=app.Spectre{7,nucell};
            indexm50=app.Spectre{8,nucell};
            indexm90=app.Spectre{9,nucell};
            indexd10=app.Spectre{10,nucell};
            indexd50=app.Spectre{11,nucell};
            indexd90=app.Spectre{12,nucell};

            app.UIAxes.Title.String=['Cell', 'No :' ,num2str(nucell)];
            plot(app.UIAxes,app.time,app.Calcium(:, nucell))
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.time, app.baseline_cal(:,nucell))
            hold(app.UIAxes,'off')

            app.UIAxes3.Title.String=['Resultat Detection Cell', 'No :' ,num2str(nucell)];
            plot(app.UIAxes3,app.time,app.Calcium_normalise(:, nucell))
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,app.time,app.Calcium_normalise_lisse(:, nucell),'r','LineWidth',3)
            plot(app.UIAxes3,app.time(pidx),app.Calcium_normalise(pidx, nucell),'bo','MarkerSize',5  )

            plot(app.UIAxes3,app.time(1:end),app.dCadt(:,nucell),'c','LineWidth',3)
            % if length(start_cal)==length(End_cal)
            plot(app.UIAxes3,app.time(start_cal),app.Calcium_normalise(start_cal,nucell),'r>','MarkerSize',8,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time(End_cal),app.Calcium_normalise(End_cal,nucell),'r<','MarkerSize',8,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time( indexm10),app.Calcium_normalise( indexm10,nucell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time( indexm50),app.Calcium_normalise( indexm50,nucell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time( indexm90),app.Calcium_normalise( indexm90,nucell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time( indexd10),app.Calcium_normalise( indexd10,nucell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time( indexd50),app.Calcium_normalise( indexd50,nucell),'rd','MarkerSize',3,'MarkerFaceColor','auto')
            plot(app.UIAxes3,app.time( indexd90),app.Calcium_normalise( indexd90,nucell),'rd','MarkerSize',3,'MarkerFaceColor','auto')


            % end
            plot(app.UIAxes3,[app.time(1) app.time(end)],[Seuil Seuil],'g','LineWidth',3)
            hold(app.UIAxes3,'off')


            plot(app.UIAxes2,freq, Power)
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,[freq(1) Power(end)],[ app.Seuil_Power  app.Seuil_Power],'LineWidth',3,'Color','yellow')
            %  set(app.UIAxes2,'Title',text('String','Power Spectrum','Color','r'))
            % xlabel(app.UIAxes2,'Frequency (Hz)')
            % ylabel(app.UIAxes2,'Power Spectral Density')
            xlim(app.UIAxes2,[0 0.2])

            hold(app.UIAxes2,'off')

        end

        % Button pushed function: ExclusionButton
        function ExclusionButtonPushed(app, event)
            [~,cur_cell]=ismember(app.CellulesListBox.Value,app.CellulesListBox.Items,'legacy');
            app.CellulesListBox.Items(cur_cell)={[num2str(cur_cell) 'X']};
            app.CellulesListBox.Value=app.CellulesListBox.Items(cur_cell);
            drawnow
        end

        % Button pushed function: InclusionButton
        function InclusionButtonPushed(app, event)
              [~,cur_cell]=ismember(app.CellulesListBox.Value,app.CellulesListBox.Items,'legacy');
                app.CellulesListBox.Items(cur_cell)={num2str(cur_cell)};
            app.CellulesListBox.Value=app.CellulesListBox.Items(cur_cell);
            drawnow
        end

        % Button pushed function: SauvegardeButton
        function SauvegardeButtonPushed(app, event)
            %*************************************************************************************************************
            Ncellules=app.Numero_Cellules(app.Numero_Cellules>0);

            Index_Resultat=ones(length(Ncellules),1);

            Index_garde=find(contains(app.CellulesListBox.Items(:),'X'));

            if ~isempty(Index_garde)
                for i=1:length( Index_garde)
                    tmp=(Index_garde(i)~=Ncellules(:));
                    Index_Resultat=Index_Resultat.*tmp;


                end


               Index_MAJ=logical(Index_Resultat);
                RemoveZero(app,Index_MAJ);

                app.Result_Calcium = table(app.Numero_Cellules,app.Numero_transitoire,...
                app.temps_Amplitude ,app.Amplitude ,app.Debut_Transitoire,app.Fin_transitoire,...
                app.temps_mont,app.temps_decroi, app.tempsm_10, app. Amplitudem_10,...
                app. tempsm_50,app. Amplitudem_50,app.tempsm_90,app.Amplitudem_90,...
                app.tempsd_10,app.Amplituded_10,app.tempsd_50,app.Amplituded_50,...
                app.tempsd_90,app.Amplituded_90,app.AUC,app.Duree_Halfwith);
                app.UITable.Data= app.Result_Calcium;
                Index_NX=find(~contains(app.CellulesListBox.Items(:),'X'));
                 app.CellulesListBox.Items=app.ncellfList(1,Index_NX)';
            end
            %****************************************************************************************************************
            NomG=[app.dir_result,app.Nom_fichier_Res];
            app.Result_Calcium.Properties.VariableNames=["No Cellules","No Transitoire","Tps Amplitude","Amplitude",...
                "Deb Transitoire","Fin Transitoire","Tps Montee","Tps Decroissance",...
                "Temps montee 10%", "Amplitude montee 10%", "Temps montee 50%", "Amplitude montee50%",...
                "Temps montee 90%","Amplitude montee90%", "Temps decroissance 10%", "Amplitude decroissance10%",...
                "Temps decroissance 50%", "Amplitude decroissance 50%","Temps decroissance 90%",...
                "Amplitude decroissance 90%","AUC","Demi Largeur"];
            writetable( app.Result_Calcium,NomG,'Delimiter','tab')

            %**************************************************************************************************************
            %
            %*************************************************************************************************************
           NomParamDetection=[app.dir_result,strtok(app.Nom_fichier_Res,'.'),'_ParamDetection.csv'];

         
             R{1,1}=num2str(app.FrequenceEchantillonageHzEditField.Value);R{1,2}=num2str(app.SeuilDetectionEditField.Value);R{1,3}=num2str(app.FenetreDetectionsecEditField.Value);
               R{1,4}=num2str(app.LissageDFFEditField.Value);R{1,5}=num2str(app.FenetreLigneBasesecEditField.Value);
               R{1,6}=num2str(app.exclusionsecEditField.Value);

           Result_ParamDetection=cell2table(R);
           Result_ParamDetection.Properties.VariableNames=["Frequence Echantillonage (Hz)","Facteur Seuil",...
               "Fenetre Detection (sec)","Lissage DFoF%","Fenetre Ligne base(sec)",...
               "Exclusion(sec)"];
            writetable(  Result_ParamDetection, NomParamDetection,'Delimiter','tab')


            NomCalNom=[app.dir_result,strtok(app.Nom_fichier_Res,'.'),'_CalciumDFoF.csv'];
            Temp_Calcium_norm=zeros(size(app.time,1),size(app.Calcium_normalise,2)+1);
            Temp_Calcium_norm(:,1)=app.time;Temp_Calcium_norm(:,2:end)=app.Calcium_normalise;
            Result_CalciumNormalise=array2table(Temp_Calcium_norm);
          
            Result_CalciumNormalise.Properties.VariableNames=[string(app.calciumVarnames)];
            writetable( Result_CalciumNormalise,NomCalNom,'Delimiter','tab')


            %
            % app.Numero_Cellules,app.Numero_transitoire,...
            %     app.temps_Amplitude ,app.Amplitude ,app.Debut_Transitoire,app.Fin_transitoire,...
            %     app.temps_mont,app.temps_decroi, app.tempsm_10, app. Amplitudem_10,...
            %                 app. tempsm_50,app. Amplitudem_50,app.tempsm_90,app.Amplitudem_90,...
            %                 app.tempsd_10,app.Amplituded_10,app.tempsd_50,app.Amplituded_50,...
            %                 app.tempsd_90,app.Amplituded_90,app.AUC,app.Duree_Halfwith
            if app.flag_protocol==1
                NomPT=[app.dir_result,strtok(app.Nom_fichier_Res,'.'),'_ProtocolTime.csv'];
                Result_Protocol=table(app.Time_DebFin_Protocol(:,1),app.Time_DebFin_Protocol(:,2));
                Result_Protocol.Properties.VariableNames=["Start","Stop"];
                writetable( Result_Protocol,NomPT,'Delimiter','tab')


                for i=1:size(app.Time_DebFin_Protocol,1)
                    NomP=[app.dir_result, app.NameProtocol{i},app.Nom_fichier_Res];
                    index=find(app.temps_Amplitude>app.Time_DebFin_Protocol(i,1) & app.temps_Amplitude<app.Time_DebFin_Protocol(i,2) );
                    Result_Calcium_Protocol= table( app.Numero_Cellules(index) ,app.Numero_transitoire(index) ,...
                        app.temps_Amplitude(index) ,app.Amplitude(index)  ,app.Debut_Transitoire(index) ,app.Fin_transitoire(index) ,...
                        app.temps_mont(index) ,app.temps_decroi(index) , app.tempsm_10(index) , app. Amplitudem_10(index) ,...
                        app. tempsm_50(index) ,app. Amplitudem_50(index) ,app.tempsm_90(index) ,app.Amplitudem_90(index) ,...
                        app.tempsd_10(index) ,app.Amplituded_10(index) ,app.tempsd_50(index) ,app.Amplituded_50(index) ,...
                        app.tempsd_90(index) ,app.Amplituded_90(index) ,app.AUC(index) ,app.Duree_Halfwith(index) );

                    Result_Calcium_Protocol.Properties.VariableNames=["No Cellules","No Transitoire","Tps Amplitude","Amplitude",...
                        "Deb Transitoire","Fin Transitoire","Tps Montee","Tps Decroissance",...
                        "Temps montee 10%", "Amplitude montee 10%", "Temps montee 50%", "Amplitude montee50%",...
                        "Temps montee 90%","Amplitude montee90%", "Temps decroissance 10%", "Amplitude decroissance10%",...
                        "Temps decroissance 50%", "Amplitude decroissance 50%","Temps decroissance 90%",...
                        "Amplitude decroissance 90%","AUC","Demi Largeur"];
                    writetable( Result_Calcium_Protocol,NomP,'Delimiter','tab')


                end
            end

        end

        % Value changed function: NomFichierResultatsEditField
        function NomFichierResultatsEditFieldValueChanged(app, event)
            app.Nom_fichier_Res = app.NomFichierResultatsEditField.Value;

        end

        % Value changed function: exclusionsecEditField
        function exclusionsecEditFieldValueChanged(app, event)
            app.Fen_Exclusion = app.exclusionsecEditField.Value;

        end

        % Value changed function: protocoleCheckBox
        function protocoleCheckBoxValueChanged(app, event)
            app.flag_protocol = app.protocoleCheckBox.Value;

        end

        % Button pushed function: extractionprotocoleButton
        function extractionprotocoleButtonPushed(app, event)
            if app.flag_protocol==1

                figure('Name','Press Return when Done','KeyPressFcn',@(obj,evt) 0)
                plot(app.time,app.Calcium(:,:))
                dcm_obj = datacursormode(gcf);
                waitfor(gcf,'CurrentCharacter');
                app.curChar=uint8(get(gcf,'CurrentCharacter'));
                app.c_info = getCursorInfo(dcm_obj);
                close(gcf)
                temp=struct2table(app.c_info);
                temp1=table2array(temp(:,2));
               % temp2=temp(:,3);

                ttrie=sort(temp1(:,1));
                app.P_be=reshape(ttrie,[2,round(size(ttrie,1)/2)])';
                %dt=mean(diff(app.time));
                ammp_max=max(max(app.Calcium(:,:)));
                colormap(jet(size(app.P_be,1)));
                cmap=colormap;
                plot(app.UIAxes,app.time,app.Calcium(:,:))
                %hold(app.UIAxes,'on')
                for i=1:size(app.P_be,1)
                    app.NameProtocol{i}=['Protocol No ',num2str(i)];
                    x=[app.P_be(i,1) app.P_be(i,1) app.P_be(i,2)  app.P_be(i,2)];
                    y=[0 ammp_max  ammp_max 0];


                  %shape = 
                  patch(app.UIAxes,x,y,cmap(i,:),'FaceAlpha',0.3,'EdgeColor','none');

                end
                  close(gcf)
               % hold(app.UIAxes,'off')
                app.Time_DebFin_Protocol=app.P_be(:,:);%.*dt;


            end
        end

        % Value changed function: FrequenceEchantillonageHzEditField
        function FrequenceEchantillonageHzEditFieldValueChanged(app, event)
            app.fr = app.FrequenceEchantillonageHzEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calcium')
            xlabel(app.UIAxes, 'time (sec)')
            ylabel(app.UIAxes, 'DF/F')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectre Fourier')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Power Spectral Density')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Resultat Detection')
            xlabel(app.UIAxes3, 'time(sec)')
            ylabel(app.UIAxes3, 'parametres Detecte')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Image Raster')
            xlabel(app.UIAxes4, 'Temps')
            ylabel(app.UIAxes4, 'N cellules')
            zlabel(app.UIAxes4, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No Cellules'; 'No Transitoire'; 'Tps Amplitude'; 'Amplitude'; 'Deb Transitoire'; 'Fin Transitoire'; 'Tps Montee'; 'Tps Decroissance'; 'Temps montee 10%'; 'Amplitude montee 10%'; 'Temps montee 50%'; 'Amplitude montee50%'; 'Temps montee 90%'; 'Amplitude montee90%'; 'Temps decroissance 10%'; 'Amplitude decroissance10%'; 'Temps decroissance 50%'; 'Amplitude decroissance50%'; 'Temps decroissance 90%'; 'Amplitude decroissance 90%'; 'AUC'; 'Demi Largeur'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.OuvrirfichiercsvButton = uibutton(app.UIFigure, 'push');
            app.OuvrirfichiercsvButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirfichiercsvButtonPushed, true);
            app.OuvrirfichiercsvButton.Text = 'Ouvrir fichier csv';
            app.CellulesListBoxLabel = uilabel(app.UIFigure);
            app.CellulesListBoxLabel.Text = 'Cellules';
            app.CellulesListBox = uilistbox(app.UIFigure);
            app.CellulesListBox.Items = {};
            app.CellulesListBox.ClickedFcn = createCallbackFcn(app, @CellulesListBoxClicked, true);
            app.CellulesListBox.Value = {};
            app.DetectionButton = uibutton(app.UIFigure, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Text = 'Detection';
            app.SeuilDetectionEditFieldLabel = uilabel(app.UIFigure);
            app.SeuilDetectionEditFieldLabel.Text = 'Seuil Detection';
            app.SeuilDetectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeuilDetectionEditField.ValueChangedFcn = createCallbackFcn(app, @SeuilDetectionEditFieldValueChanged, true);
            app.SeuilDetectionEditField.Value = 2;
            app.LissageDFFEditFieldLabel = uilabel(app.UIFigure);
            app.LissageDFFEditFieldLabel.Text = 'Lissage DF/F %';
            app.LissageDFFEditField = uieditfield(app.UIFigure, 'numeric');
            app.LissageDFFEditField.ValueChangedFcn = createCallbackFcn(app, @LissageDFFEditFieldValueChanged, true);
            app.LissageDFFEditField.Value = 0.005;
            app.FenetreLigneBasesecEditFieldLabel = uilabel(app.UIFigure);
            app.FenetreLigneBasesecEditFieldLabel.Text = 'Fenetre  Ligne Base(sec)';
            app.FenetreLigneBasesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.FenetreLigneBasesecEditField.ValueChangedFcn = createCallbackFcn(app, @FenetreLigneBasesecEditFieldValueChanged, true);
            app.FenetreLigneBasesecEditField.Value = 800;
            app.FenetreDetectionLabel = uilabel(app.UIFigure);
            app.FenetreDetectionLabel.Text = 'Fenetre  Detection (sec)';
            app.FenetreDetectionsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.FenetreDetectionsecEditField.Limits = [1 50];
            app.FenetreDetectionsecEditField.ValueChangedFcn = createCallbackFcn(app, @FenetreDetectionsecEditFieldValueChanged, true);
            app.FenetreDetectionsecEditField.Value = 20;
            app.SauvegardeButton = uibutton(app.UIFigure, 'push');
            app.SauvegardeButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegardeButtonPushed, true);
            app.SauvegardeButton.Text = 'Sauvegarde';
            app.NomFichierResultatsEditFieldLabel = uilabel(app.UIFigure);
            app.NomFichierResultatsEditFieldLabel.Text = 'Nom Fichier Resultats';
            app.NomFichierResultatsEditField = uieditfield(app.UIFigure, 'text');
            app.NomFichierResultatsEditField.ValueChangedFcn = createCallbackFcn(app, @NomFichierResultatsEditFieldValueChanged, true);
            app.NomfichieraanalyserEditFieldLabel = uilabel(app.UIFigure);
            app.NomfichieraanalyserEditFieldLabel.Text = 'Nom fichier a analyser';
            app.NomfichieraanalyserEditField = uieditfield(app.UIFigure, 'text');
            app.cheminEditFieldLabel = uilabel(app.UIFigure);
            app.cheminEditFieldLabel.Text = 'chemin';
            app.cheminEditField = uieditfield(app.UIFigure, 'text');
            app.exclusionsecEditFieldLabel = uilabel(app.UIFigure);
            app.exclusionsecEditFieldLabel.Text = 'exclusion (sec)';
            app.exclusionsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.exclusionsecEditField.ValueChangedFcn = createCallbackFcn(app, @exclusionsecEditFieldValueChanged, true);
            app.exclusionsecEditField.Value = 50;
            app.protocoleCheckBox = uicheckbox(app.UIFigure);
            app.protocoleCheckBox.ValueChangedFcn = createCallbackFcn(app, @protocoleCheckBoxValueChanged, true);
            app.protocoleCheckBox.Text = 'protocole';
            app.extractionprotocoleButton = uibutton(app.UIFigure, 'push');
            app.extractionprotocoleButton.ButtonPushedFcn = createCallbackFcn(app, @extractionprotocoleButtonPushed, true);
            app.extractionprotocoleButton.Text = 'extraction protocole';
            app.ExclusionButton = uibutton(app.UIFigure, 'push');
            app.ExclusionButton.ButtonPushedFcn = createCallbackFcn(app, @ExclusionButtonPushed, true);
            app.ExclusionButton.Text = 'Exclusion';
            app.InclusionButton = uibutton(app.UIFigure, 'push');
            app.InclusionButton.ButtonPushedFcn = createCallbackFcn(app, @InclusionButtonPushed, true);
            app.InclusionButton.Text = 'Inclusion';
            app.FrequenceEchantillonageHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequenceEchantillonageHzEditFieldLabel.Text = 'Frequence Echantillonage(Hz)';
            app.FrequenceEchantillonageHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequenceEchantillonageHzEditField.Limits = [1 10000];
            app.FrequenceEchantillonageHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequenceEchantillonageHzEditFieldValueChanged, true);
            app.FrequenceEchantillonageHzEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cal_Analyse_ver1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end